classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        CalendarTab               matlab.ui.container.Tab
        CalendarButtonGroup       matlab.ui.container.ButtonGroup
        EventsButton              matlab.ui.control.Button
        Button_42                 matlab.ui.control.Button
        Button_41                 matlab.ui.control.Button
        Button_40                 matlab.ui.control.Button
        Button_39                 matlab.ui.control.Button
        Button_38                 matlab.ui.control.Button
        Button_37                 matlab.ui.control.Button
        Button_36                 matlab.ui.control.Button
        Button_35                 matlab.ui.control.Button
        Button_34                 matlab.ui.control.Button
        Button_33                 matlab.ui.control.Button
        Button_32                 matlab.ui.control.Button
        Button_31                 matlab.ui.control.Button
        Button_30                 matlab.ui.control.Button
        Button_29                 matlab.ui.control.Button
        Button_28                 matlab.ui.control.Button
        Button_27                 matlab.ui.control.Button
        Button_26                 matlab.ui.control.Button
        Button_25                 matlab.ui.control.Button
        Button_24                 matlab.ui.control.Button
        Button_23                 matlab.ui.control.Button
        Button_22                 matlab.ui.control.Button
        Button_21                 matlab.ui.control.Button
        Button_20                 matlab.ui.control.Button
        Button_19                 matlab.ui.control.Button
        Button_18                 matlab.ui.control.Button
        Button_17                 matlab.ui.control.Button
        Button_16                 matlab.ui.control.Button
        Button_15                 matlab.ui.control.Button
        Button_14                 matlab.ui.control.Button
        Button_13                 matlab.ui.control.Button
        Button_12                 matlab.ui.control.Button
        Button_11                 matlab.ui.control.Button
        Button_10                 matlab.ui.control.Button
        Button_9                  matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        April2023Label            matlab.ui.control.Label
        MondayTab                 matlab.ui.container.Tab
        TuesdayTab                matlab.ui.container.Tab
        WednesdayTab              matlab.ui.container.Tab
        ThursdayTab               matlab.ui.container.Tab
        FridayTab                 matlab.ui.container.Tab
        RemindersPanel            matlab.ui.container.Panel
        ClickmetosetatimerButton  matlab.ui.control.Button
        Spinner_2                 matlab.ui.control.Spinner
        AchievementsLabel         matlab.ui.control.Label
        Spinner                   matlab.ui.control.Spinner
        CoffeeCountLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: RemindersPanel
        function RemindersPanelSizeChanged(app, event)

        end

        % Size changed function: CalendarTab
        function CalendarTabSizeChanged(app, event)
           
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
    % Create a UI to input the task details
    prompt = {'Event Title:', 'Specific Time (HH:MM):', 'Repetitions:', 'Notify Before (minutes):'};
    dlgtitle = 'Task Details';
    dims = [1, 50];
    definput = {'Sample Event', '12:00', '1', '5'}; % Default values for testing purposes
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    
    % Check if the user entered the task details
    if isempty(answer)
        return;
    end
    
    % Extract the inputted details
    eventTitle = answer{1};
    specificTime = answer{2};
    repetitions = str2double(answer{3});
    notifyBefore = str2double(answer{4});
    
    % Display the task details
    disp(['Event Title: ', eventTitle]);
    disp(['Specific Time: ', specificTime]);
    disp(['Repetitions: ', num2str(repetitions)]);
    disp(['Notify Before: ', num2str(notifyBefore), ' minutes']);
    
    % Set the reminder to notify the user before the event
    reminderTime = datetime('now', 'Format', 'HH:mm') + ...
                   duration(0, notifyBefore, 0);
    targetTime = datetime(specificTime, 'InputFormat', 'HH:mm');
    
    % Calculate the time difference between now and the reminder time
    timeDifference = targetTime - reminderTime;
    
    % Set the alarm and display a message box when the reminder time is reached
    pause(seconds(timeDifference));
    msgbox(['Reminder: "', eventTitle, '" starts in ', num2str(notifyBefore), ' minutes!'], 'Reminder');
        end

        % Button pushed function: ClickmetosetatimerButton
        function ClickmetosetatimerButtonPushed(app, event)
            % Create a UI to input the time for the reminder
    prompt = {'Enter the reminder time (HH:MM:SS):'};
    dlgtitle = 'Set Reminder';
    dims = [1, 50];
    definput = {'00:00:10'}; % Default value for testing purposes
    answer = inputdlg(prompt, dlgtitle, dims, definput);
    
    % Check if the user entered the time
    if isempty(answer)
        return;
    end
    
    % Convert the time string to a duration object
    reminderTime = duration(answer, 'InputFormat', 'hh:mm:ss');
    
    % Calculate the time difference between now and the reminder time
    currentTime = datetime('now', 'Format', 'HH:mm:ss');
    targetTime = dateshift(currentTime, 'start', 'minute') + reminderTime;
    timeDifference = targetTime - currentTime;
    
    % Set the alarm and display a message box when the time is reached
    pause(seconds(timeDifference));
    msgbox('Reminder: Time is up!', 'Reminder');
        end

        % Button pushed function: EventsButton
        function EventsButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RemindersPanel = uipanel(app.UIFigure);
            app.RemindersPanel.Title = 'Reminders';
            app.RemindersPanel.SizeChangedFcn = createCallbackFcn(app, @RemindersPanelSizeChanged, true);
            app.CoffeeCountLabel = uilabel(app.RemindersPanel);
            app.CoffeeCountLabel.Text = 'Coffee Count';
            app.Spinner = uispinner(app.RemindersPanel);
            app.AchievementsLabel = uilabel(app.RemindersPanel);
            app.AchievementsLabel.Text = 'Achievements';
            app.Spinner_2 = uispinner(app.RemindersPanel);
            app.ClickmetosetatimerButton = uibutton(app.RemindersPanel, 'push');
            app.ClickmetosetatimerButton.ButtonPushedFcn = createCallbackFcn(app, @ClickmetosetatimerButtonPushed, true);
            app.ClickmetosetatimerButton.Text = 'Click me to set a timer!';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalendarTab = uitab(app.TabGroup);
            app.CalendarTab.SizeChangedFcn = createCallbackFcn(app, @CalendarTabSizeChanged, true);
            app.CalendarTab.Title = 'Calendar';
            app.CalendarButtonGroup = uibuttongroup(app.CalendarTab);
            app.CalendarButtonGroup.Title = 'Calendar';
            app.April2023Label = uilabel(app.CalendarButtonGroup);
            app.April2023Label.Text = 'April 2023';
            app.Button = uibutton(app.CalendarButtonGroup, 'push');
            app.Button.Text = '26';
            app.Button_2 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_2.Text = '27';
            app.Button_3 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_3.Text = '28';
            app.Button_4 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_4.Text = '29';
            app.Button_5 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_5.Text = '30';
            app.Button_6 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_6.Text = '31';
            app.Button_7 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '1';
            app.Button_8 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_8.Text = '2';
            app.Button_9 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_9.Text = '3';
            app.Button_10 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_10.Text = '4';
            app.Button_11 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_11.Text = '5';
            app.Button_12 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_12.Text = '6';
            app.Button_13 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_13.Text = '7';
            app.Button_14 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_14.Text = '8';
            app.Button_15 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_15.Text = '9';
            app.Button_16 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_16.Text = '10';
            app.Button_17 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_17.Text = '11';
            app.Button_18 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_18.Text = '12';
            app.Button_19 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_19.Text = '13';
            app.Button_20 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_20.Text = '14';
            app.Button_21 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_21.Text = '15';
            app.Button_22 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_22.Text = '16';
            app.Button_23 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_23.Text = '17';
            app.Button_24 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_24.Text = '18';
            app.Button_25 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_25.Text = '19';
            app.Button_26 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_26.Text = '20';
            app.Button_27 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_27.Text = '21';
            app.Button_28 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_28.Text = '22';
            app.Button_29 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_29.Text = '23';
            app.Button_30 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_30.Text = '24';
            app.Button_31 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_31.Text = '25';
            app.Button_32 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_32.Text = '26';
            app.Button_33 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_33.Text = '27';
            app.Button_34 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_34.Text = '28';
            app.Button_35 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_35.Text = '29';
            app.Button_36 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_36.Text = '30';
            app.Button_37 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_37.Text = '31';
            app.Button_38 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_38.Text = '1';
            app.Button_39 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_39.Text = '2';
            app.Button_40 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_40.Text = '3';
            app.Button_41 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_41.Text = '4';
            app.Button_42 = uibutton(app.CalendarButtonGroup, 'push');
            app.Button_42.Text = '5';
            app.EventsButton = uibutton(app.CalendarButtonGroup, 'push');
            app.EventsButton.ButtonPushedFcn = createCallbackFcn(app, @EventsButtonPushed, true);
            app.EventsButton.Text = 'Events';
            app.MondayTab = uitab(app.TabGroup);
            app.MondayTab.Title = 'Monday';
            app.TuesdayTab = uitab(app.TabGroup);
            app.TuesdayTab.Title = 'Tuesday';
            app.WednesdayTab = uitab(app.TabGroup);
            app.WednesdayTab.Title = 'Wednesday';
            app.ThursdayTab = uitab(app.TabGroup);
            app.ThursdayTab.Title = 'Thursday';
            app.FridayTab = uitab(app.TabGroup);
            app.FridayTab.Title = 'Friday';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end